classdef msynth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OscillatorPanel          matlab.ui.container.Panel
        oscWaveKnob              matlab.ui.control.DiscreteKnob
        oscAmpKnob               matlab.ui.control.Knob
        oscFreqField             matlab.ui.control.NumericEditField
        oscWaveKnob_2            matlab.ui.control.DiscreteKnob
        oscAmpKnob_2             matlab.ui.control.Knob
        oscFreqField_2           matlab.ui.control.NumericEditField
        oscWaveKnob_3            matlab.ui.control.DiscreteKnob
        oscAmpKnob_3             matlab.ui.control.Knob
        oscFreqField_3           matlab.ui.control.NumericEditField
        oscAxes                  matlab.ui.control.UIAxes
        ALabel                   matlab.ui.control.Label
        fLabel                   matlab.ui.control.Label
        oscWaveKnob_4            matlab.ui.control.DiscreteKnob
        oscAmpKnob_4             matlab.ui.control.Knob
        oscFreqField_4           matlab.ui.control.NumericEditField
        oscWaveKnob_5            matlab.ui.control.DiscreteKnob
        oscAmpKnob_5             matlab.ui.control.Knob
        oscFreqField_5           matlab.ui.control.NumericEditField
        oscHarmSpinner           matlab.ui.control.Spinner
        oscHarmSpinner_2         matlab.ui.control.Spinner
        oscWaveKnob_6            matlab.ui.control.DiscreteKnob
        oscAmpKnob_6             matlab.ui.control.Knob
        oscFreqField_6           matlab.ui.control.NumericEditField
        KeyPressDurationsecEditFieldLabel  matlab.ui.control.Label
        keyDurationField         matlab.ui.control.NumericEditField
        Button                   matlab.ui.control.Button
        FilterPanel              matlab.ui.container.Panel
        ButterworthLPFSlider     matlab.ui.control.Slider
        filterAxes               matlab.ui.control.UIAxes
        ButterworthHPFSlider     matlab.ui.control.Slider
        ButterworthHPFSwitch     matlab.ui.control.Switch
        ButterHPFLabel           matlab.ui.control.Label
        ButterworthLPFSwitch     matlab.ui.control.Switch
        ButterLPFLabel           matlab.ui.control.Label
        ChebyshevHPFSwitch       matlab.ui.control.Switch
        Cheby1HPFLabel           matlab.ui.control.Label
        ChebyshevHPFSlider       matlab.ui.control.Slider
        ChebyshevHPFpbSlider     matlab.ui.control.Slider
        ChebyshevLPFSwitch       matlab.ui.control.Switch
        Cheby1LPFLabel           matlab.ui.control.Label
        ChebyshevLPFSlider       matlab.ui.control.Slider
        ChebyshevLPFpbSlider     matlab.ui.control.Slider
        ButterworthBSFfclSlider  matlab.ui.control.Slider
        ButterworthBSFSwitch     matlab.ui.control.Switch
        ButterBSFLabel           matlab.ui.control.Label
        ButterworthBSFfchSlider  matlab.ui.control.Slider
        ButterworthBPFfclSlider  matlab.ui.control.Slider
        ButterworthBPFSwitch     matlab.ui.control.Switch
        ButterBPFLabel           matlab.ui.control.Label
        ButterworthBPFfchSlider  matlab.ui.control.Slider
        ChebyshevBPFfclSlider_2  matlab.ui.control.Slider
        ChebyshevBPFSwitch_2     matlab.ui.control.Switch
        Cheby2BPFLabel           matlab.ui.control.Label
        ChebyshevBPFfchSlider_2  matlab.ui.control.Slider
        ChebyshevBPFfclSlider    matlab.ui.control.Slider
        ChebyshevBPFSwitch       matlab.ui.control.Switch
        Cheby1BPFLabel           matlab.ui.control.Label
        ChebyshevBPFfchSlider    matlab.ui.control.Slider
        ButterworthHPFField      matlab.ui.control.NumericEditField
        ButterworthLPFField      matlab.ui.control.NumericEditField
        ButterworthBSFfclField   matlab.ui.control.NumericEditField
        ButterworthBSFfchField   matlab.ui.control.NumericEditField
        ButterworthBPFfclField   matlab.ui.control.NumericEditField
        ButterworthBPFfchField   matlab.ui.control.NumericEditField
        ChebyshevBPFfclField_2   matlab.ui.control.NumericEditField
        ChebyshevBPFfchField_2   matlab.ui.control.NumericEditField
        ChebyshevBPFfclField     matlab.ui.control.NumericEditField
        ChebyshevBPFfchField     matlab.ui.control.NumericEditField
        ChebyshevLPFField        matlab.ui.control.NumericEditField
        ChebyshevHPFField        matlab.ui.control.NumericEditField
        ButterworthHPFSpinner    matlab.ui.control.Spinner
        ButterworthLPFSpinner    matlab.ui.control.Spinner
        ButterworthBSFSpinner    matlab.ui.control.Spinner
        ChebyshevHPFSpinner      matlab.ui.control.Spinner
        ChebyshevLPFSpinner      matlab.ui.control.Spinner
        ChebyshevBPFSpinner      matlab.ui.control.Spinner
        ChebyshevBPFSpinner_2    matlab.ui.control.Spinner
        ButterworthBPFSpinner    matlab.ui.control.Spinner
        EnvelopePanel            matlab.ui.container.Panel
        envAxes                  matlab.ui.control.UIAxes
        AKnobLabel               matlab.ui.control.Label
        AKnob                    matlab.ui.control.Knob
        DKnobLabel               matlab.ui.control.Label
        DKnob                    matlab.ui.control.Knob
        RKnobLabel               matlab.ui.control.Label
        RKnob                    matlab.ui.control.Knob
        SSliderLabel             matlab.ui.control.Label
        SSlider                  matlab.ui.control.Slider
        AmplifierPanel           matlab.ui.container.Panel
        AMPSliderLabel           matlab.ui.control.Label
        ampSlider                matlab.ui.control.Slider
        envPlayButton            matlab.ui.control.Button
        filterPlayButton         matlab.ui.control.Button
        oscPlayButton            matlab.ui.control.Button
        msynthLabel              matlab.ui.control.Label
        SavedPresetPanel         matlab.ui.container.Panel
        SaveSwitch               matlab.ui.control.RockerSwitch
        NoteButton               matlab.ui.control.Button
        NoteButton_2             matlab.ui.control.Button
        NoteButton_3             matlab.ui.control.Button
        NoteButton_4             matlab.ui.control.Button
        NoteButton_5             matlab.ui.control.Button
        NoteButton_6             matlab.ui.control.Button
        NoteButton_7             matlab.ui.control.Button
        NoteButton_8             matlab.ui.control.Button
    end


    properties (Access = private)
        osc1y % y of oscillator 1
        osc2y % y of oscillator 2
        osc3y % y of oscillator 3
        osc4y % y of oscillator 4
        osc5y % y of oscillator 5
        osc6y % y of oscillator 6
        oscy % y of all oscillators
        
        Fs = 44200 % sample rate 
        t % total time (play time + release time)
        kp % key pressed length
        
        filtery % y after filters
        
        envy % y after envelope
        ADSR %ADSR
        
        ampy % y after amplifier
        
        y % final y (to be implemented after lfo)
        
        n1y = 0; % saved y for note button 1
        n2y = 0; % saved y for note button 2
        n3y = 0; % saved y for note button 3
        n4y = 0; % saved y for note button 4
        n5y = 0; % saved y for note button 5
        n6y = 0; % saved y for note button 6
        n7y = 0; % saved y for note button 7
        n8y = 0; % saved y for note button 8
    end

    methods (Access = private)
        
        %compute app.t
        function results = playtime(app)
            T = app.keyDurationField.Value;%*(1/f);
            dt = 1/app.Fs;
            app.t = 0:dt:T;
        end
        
        function results = totaltime(app)
            Tp = app.keyDurationField.Value;
            T = Tp + app.RKnob.Value/1000;
            dt = 1/app.Fs;
            app.t = 0:dt:T;
            app.kp = round(Tp*app.Fs);
        end
        
        
        %oscillator
        %generating wave for oscillator 1
        function results = osc1(app)
            
            A = app.oscAmpKnob.Value;
            f = app.oscFreqField.Value;
            harmonics = app.oscHarmSpinner.Value;
            app.osc1y = zeros(size(app.t));
            
            switch app.oscWaveKnob.Value
                case 'Sine'
                    for h = 1:(harmonics)
                        app.osc1y = app.osc1y + A*sin(2*pi*app.t*f*h);
                    end
                    
                case 'Triangle'
                    for h = 1:(harmonics)
                        app.osc1y = app.osc1y + A*sawtooth(2*pi*app.t*f*h, 0.5);
                    end
                    
                case 'Square'
                    for h = 1:(harmonics)
                        app.osc1y = app.osc1y + A*square(2*pi*app.t*f*h);
                    end
                    
                case 'Sawtooth'
                    for h = 1:(harmonics)
                        app.osc1y = app.osc1y + A*sawtooth(2*pi*app.t*f*h);
                    end
                    
                case 'Noise'
                    app.osc1y = awgn(A*app.t./app.t, 10);   
                    
                otherwise %off
                    
            end
            
        end
        
        %generating wave for oscillator 2
        function results = osc2(app)
            
            A = app.oscAmpKnob_2.Value;
            f = app.oscFreqField_2.Value;
            harmonics = app.oscHarmSpinner_2.Value;
            app.osc2y = zeros(size(app.t));
            
            switch app.oscWaveKnob_2.Value
                case 'Sine'
                    for h = 1:(harmonics)
                        app.osc2y = app.osc2y + A/h*sin(2*pi*app.t*f*h);
                    end
                    
                case 'Triangle'
                    for h = 1:(harmonics)
                        app.osc2y = app.osc2y + A/h*sawtooth(2*pi*app.t*f*h, 0.5);
                    end
                    
                case 'Square'
                    for h = 1:(harmonics)
                        app.osc2y = app.osc2y + A/h*square(2*pi*app.t*f*h);
                    end
                    
                case 'Sawtooth'
                    for h = 1:(harmonics)
                        app.osc2y = app.osc2y + A/h*sawtooth(2*pi*app.t*f*h);
                    end
                    
                case 'Noise'
                    app.osc2y = awgn(A*app.t./app.t, 10);   
                    
                otherwise %off
                    
            end
        end
        
        %generating wave for oscillator 3
        function results = osc3(app)
            
            A = app.oscAmpKnob_3.Value;
            f = app.oscFreqField_3.Value;
            
            switch app.oscWaveKnob_3.Value
                case 'Sine'
                    app.osc3y = A*sin(2*pi*app.t*f);
                    
                case 'Triangle'
                    app.osc3y = A*sawtooth(2*pi*app.t*f, 0.5);
                    
                case 'Square'
                    app.osc3y = A*square(2*pi*app.t*f);
                    
                case 'Sawtooth'
                    app.osc3y = A*sawtooth(2*pi*app.t*f);
                    
                case 'Noise'
                    app.osc3y = awgn(A*app.t./app.t, 10);
                    
                otherwise %off
                    app.osc3y = zeros(size(app.t));
            end
        end
        
        %generating wave for oscillator 4
        function results = osc4(app)
            
            A = app.oscAmpKnob_4.Value;
            f = app.oscFreqField_4.Value;
            
            switch app.oscWaveKnob_4.Value
                case 'Sine'
                    app.osc4y = A*sin(2*pi*app.t*f);
                    
                case 'Triangle'
                    app.osc4y = A*sawtooth(2*pi*app.t*f, 0.5);
                    
                case 'Square'
                    app.osc4y = A*square(2*pi*app.t*f);
                    
                case 'Sawtooth'
                    app.osc4y = A*sawtooth(2*pi*app.t*f);
                    
                case 'Noise'
                    app.osc4y = awgn(A*app.t./app.t, 10);
                    
                otherwise %off
                    app.osc4y = zeros(size(app.t));
            end
        end
        
        %generating wave for oscillator 5
        function results = osc5(app)
            
            A = app.oscAmpKnob_5.Value;
            f = app.oscFreqField_5.Value;
            
            switch app.oscWaveKnob_5.Value
                case 'Sine'
                    app.osc5y = A*sin(2*pi*app.t*f);
                    
                case 'Triangle'
                    app.osc5y = A*sawtooth(2*pi*app.t*f, 0.5);
                    
                case 'Square'
                    app.osc5y = A*square(2*pi*app.t*f);
                    
                case 'Sawtooth'
                    app.osc5y = A*sawtooth(2*pi*app.t*f);
                    
                case 'Noise'
                    app.osc5y = awgn(A*app.t./app.t, 10);
                    
                otherwise %off
                    app.osc5y = zeros(size(app.t));
            end
        end
        
        %generating wave for oscillator 6
        function results = osc6(app)
            
            A = app.oscAmpKnob_6.Value;
            f = app.oscFreqField_6.Value;
            
            switch app.oscWaveKnob_6.Value
                case 'Sine'
                    app.osc6y = A*sin(2*pi*app.t*f);
                    
                case 'Triangle'
                    app.osc6y = A*sawtooth(2*pi*app.t*f, 0.5);
                    
                case 'Square'
                    app.osc6y = A*square(2*pi*app.t*f);
                    
                case 'Sawtooth'
                    app.osc6y = A*sawtooth(2*pi*app.t*f);
                    
                case 'Noise'
                    app.osc6y = awgn(A*app.t./app.t, 10);
                    
                otherwise %off
                    app.osc6y = zeros(size(app.t));
            end
        end
        
        function results = osc(app)
            osc1(app);
            osc2(app);
            osc3(app);
            osc4(app);
            osc5(app);
            osc6(app);
            app.oscy = app.osc1y + app.osc2y + app.osc3y + app.osc4y + app.osc5y + app.osc6y;
        end

        function results = oscplot(app)
            plot(app.oscAxes, app.t(1:1000), app.oscy(1:1000));
%             ylim(app.oscAxes, [-5 5])
        end
        
        function results = oscplay(app)
            player = audioplayer(app.oscy,app.Fs);
            playblocking(player);
            stop(player);
        end


        % filter
        function results = butterhpf(app)
            fc = app.ButterworthHPFSlider.Value; % cutoff frequency
            order = app.ButterworthHPFSpinner.Value;
            [b, a] = butter(order, fc/(app.Fs/2), 'high'); %HP butterworth filter, fc/(Fs/2) rad/sample
            app.filtery = filter(b, a, app.filtery);
            
            [h, w] = freqz(b, a);
            yyaxis (app.filterAxes, 'right');
            plot(app.filterAxes, w, h, 'red');
        end
        
        function results = butterlpf(app)
            fc = app.ButterworthLPFSlider.Value; % cutoff frequency
            order = app.ButterworthLPFSpinner.Value;
            [b, a] = butter(order, fc/(app.Fs/2)); %LP butterworth filter
            app.filtery = filter(b, a, app.filtery);
            
            [h, w] = freqz(b, a);
            yyaxis (app.filterAxes, 'right');
            hold (app.filterAxes, 'on');
            plot(app.filterAxes, w, h, 'red');
        end
        
        function results = butterbsf(app)
            fcl = app.ButterworthBSFfclSlider.Value;
            fch = app.ButterworthBSFfchSlider.Value;
            order = app.ButterworthBSFSpinner.Value/2;
            [b,a] = butter(order,[fch/(app.Fs/2) fcl/(app.Fs/2)],'stop'); %butterworth BSF
            app.filtery = filter(b, a, app.filtery);
            
            [h, w] = freqz(b, a);
            yyaxis (app.filterAxes, 'right');
            hold (app.filterAxes, 'on');
            plot(app.filterAxes, w, h, 'red');
        end
        
        function results = butterbpf(app)
            fcl = app.ButterworthBPFfclSlider.Value;
            fch = app.ButterworthBPFfchSlider.Value;
            order = app.ButterworthBPFSpinner.Value/2;
            [b,a] = butter(order,[fch/(app.Fs/2) fcl/(app.Fs/2)],'bandpass'); %butterworth BPF
            app.filtery = filter(b, a, app.filtery);
            
            [h, w] = freqz(b, a);
            yyaxis (app.filterAxes, 'right');
            hold (app.filterAxes, 'on');
            plot(app.filterAxes, w, h, 'red');
        end
        
        function results = chebybpf(app)
            fcl = app.ChebyshevBPFfclSlider.Value;
            fch = app.ChebyshevBPFfchSlider.Value;
            order = app.ChebyshevBPFSpinner.Value;
            pbr = 3; %passband ripple
            [A,B,C,D] = cheby1(order/2, pbr, [fch/(app.Fs/2) fcl/(app.Fs/2)]); %chevyshev1 BSF
            d = designfilt('bandpassiir','FilterOrder',order,'PassbandFrequency1',fch,'PassbandFrequency2',fcl,'PassbandRipple',pbr,'SampleRate',app.Fs);
            sos = ss2sos(A,B,C,D);
            app.filtery = filter2(sos, app.filtery);

            [h, w] = freqz(sos);
            yyaxis (app.filterAxes, 'right');
            hold (app.filterAxes, 'on');
            plot(app.filterAxes, w, h, 'red');
        end
        
        function results = cheby2bpf(app)
            fcl = app.ChebyshevBPFfclSlider_2.Value;
            fch = app.ChebyshevBPFfchSlider_2.Value;
            order = app.ChebyshevBPFSpinner_2.Value;
            pbr = 3; %passband ripple
            [A,B,C,D] = cheby2(order/2, pbr, [fch/(app.Fs/2) fcl/(app.Fs/2)]); %chevyshev2 BSF
            d = designfilt('bandpassiir','FilterOrder',order,'PassbandFrequency1',fch,'PassbandFrequency2',fcl,'PassbandRipple',pbr,'SampleRate',app.Fs);
            sos = ss2sos(A,B,C,D);
            app.filtery = filter2(sos, app.filtery);

            [h, w] = freqz(sos);
            yyaxis (app.filterAxes, 'right');
            hold (app.filterAxes, 'on');
            plot(app.filterAxes, w, h, 'red');
        end
        
        function results = chebyhpf(app)
            fc = app.ChebyshevHPFSlider.Value; % cutoff frequency
            pb = app.ChebyshevHPFpbSlider.Value; % passband ripple
            order = app.ChebyshevHPFSpinner.Value;
            [b, a] = cheby1(order, pb, fc/(app.Fs/2), 'high'); %HP chebyshev type 1 filter, fc/(Fs/2) rad/sample
            app.filtery = filter(b, a, app.filtery);
            
            [h, w] = freqz(b, a);
            yyaxis (app.filterAxes, 'right');
            hold (app.filterAxes, 'on');
            plot(app.filterAxes, w, h, 'red');
        end
        
        function results = chebylpf(app)
            fc = app.ChebyshevLPFSlider.Value; % cutoff frequency
            pb = app.ChebyshevLPFpbSlider.Value; % passband ripple
            order = app.ChebyshevLPFSpinner.Value;
            [b, a] = cheby1(order, pb, fc/(app.Fs/2)); % order LP chebyshev type 1 filter
            app.filtery = filter(b, a, app.filtery);
            
            [h, w] = freqz(b, a);
            yyaxis (app.filterAxes, 'right');
            hold (app.filterAxes, 'on');
            plot(app.filterAxes, w, h, 'red');
        end
        
        function results = filt(app)
            osc(app);
            app.filtery = app.oscy;
            if strcmp(app.ButterworthHPFSwitch.Value, 'on')
                butterhpf(app);
            end
            
            if strcmp(app.ButterworthLPFSwitch.Value, 'on')
                butterlpf(app);
            end
            
            if strcmp(app.ButterworthBSFSwitch.Value, 'on')
                butterbsf(app);
            end
            
            if strcmp(app.ButterworthBPFSwitch.Value, 'on')
                butterbpf(app);
            end
            
            if strcmp(app.ChebyshevBPFSwitch.Value, 'on')
                chebybpf(app);
            end
            
            if strcmp(app.ChebyshevBPFSwitch_2.Value, 'on')
                cheby2bpf(app);
            end

            if strcmp(app.ChebyshevHPFSwitch.Value, 'on')
                chebyhpf(app);
            end
            
            if strcmp(app.ChebyshevLPFSwitch.Value, 'on')
                chebylpf(app);
            end
        end
        
        function results = filtpsdplot(app)
            %compute psd
            [pxx_osc, f_osc] = pwelch(app.oscy);
            [pxx, f] = pwelch(app.filtery);
            
            %plot
            hold (app.filterAxes, 'on');
            yyaxis (app.filterAxes, 'left');
            plot(app.filterAxes, f_osc, pxx_osc, 'blue');
            hold (app.filterAxes, 'on');
            plot(app.filterAxes, f, pxx, 'black');
        end
        
        function results = filtplay(app)
            player = audioplayer(app.filtery,app.Fs);
            playblocking(player);
            stop(player);
        end

        % ADSR envelope
        function results = attack(app, l)
            A = 0:1/(l-1):1;
            app.envy(1:l) = app.envy(1:l).*A;
        end
        
        function results = decay(app, l)
            S = app.SSlider.Value(); % Sustain level
            D = 1:-1/(l-1):S;
        end
        
        function results = envelope(app)
            filt(app);
            app.envy = app.filtery;
%             l = length(app.envy);
            
            lA = round(app.AKnob.Value()/1000*app.Fs); % length of Attack
            lD = round(app.DKnob.Value()/1000*app.Fs); % length of Decay
            SL = app.SSlider.Value(); % Sustain level
            lR = round(app.RKnob.Value()/1000*app.Fs); %length of Release       
            
            if (SL == 1)
                SL = 0.999;
            end
            A = 0:1/(lA-1):1;
            D = 1:(SL-1)/(lD-1):SL;
            app.ADSR = [A D]; 
            lS = app.kp-lA-lD;
            if (lS > 0)
                S = SL*ones(1, lS);
                app.ADSR = [A D S];
            else
                app.ADSR = app.ADSR(1:app.kp);
            end
            
            app.envy(1:app.kp) = app.envy(1:app.kp).*app.ADSR(1:app.kp);
            if (lR > 0)
                R = app.ADSR(app.kp):-app.ADSR(app.kp)/(lR-1):0;
                app.envy(app.kp+1:length(R)+app.kp) = app.envy(app.kp+1:length(R)+app.kp).*R; 
                
                app.ADSR = [app.ADSR R];
            end
        end
        
        function results = envplay(app)
            player = audioplayer(app.envy,app.Fs);
            playblocking(player);
            stop(player);
        end
        
        function results = envplot(app)
            yyaxis (app.envAxes, 'right');
            plot(app.envAxes, app.t(1:length(app.ADSR)), app.ADSR, 'LineWidth',3);
            ylim(app.envAxes, [0 1])
            
            yyaxis (app.envAxes, 'left');
            plot(app.envAxes, app.t, app.envy);
        end
           
        % amplifier
        function results = amplifier(app)
            envelope(app);
            ampfactor = app.ampSlider.Value();
            app.ampy = app.envy*ampfactor;            
        end
        
        function results = ampplay(app)
            player = audioplayer(app.ampy,app.Fs);
            playblocking(player);
            stop(player);
        end
        
        function results = plotall(app)
            oscplot(app);
            filtpsdplot(app);
            try
                cla(app.envAxes,'reset')
                envplot(app);
            catch
            end
        end
        
        function results = presave(app)
            totaltime(app);
            amplifier(app);
            cla(app.filterAxes,'reset')
        end
        
        function results = play(app)
            player = audioplayer(app.y,app.Fs);
            playblocking(player);
            stop(player);
        end
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            totaltime(app);
            cla(app.filterAxes,'reset')
            amplifier(app);
            plotall(app);
            ampplay(app);
        end

        % Button pushed function: oscPlayButton
        function oscPlayButtonPushed(app, event)
            playtime(app);
            osc(app);
            oscplot(app);
            oscplay(app);
        end

        % Button pushed function: filterPlayButton
        function filterPlayButtonPushed(app, event)
            cla(app.filterAxes,'reset')
            playtime(app);
            filt(app);
            filtpsdplot(app);
            filtplay(app);
        end

        % Value changed function: ButterworthHPFSwitch
        function ButterworthHPFSwitchValueChanged(app, event)
            if strcmp(app.ButterworthHPFSwitch.Value, 'on')
                app.ButterworthHPFSlider.Enable = 'on';
                app.ButterworthHPFField.Enable = 'on';
                app.ButterworthHPFSpinner.Enable = 'on';
            else
                app.ButterworthHPFSlider.Enable = 'off';
                app.ButterworthHPFField.Enable = 'off';
                app.ButterworthHPFSpinner.Enable = 'off';
            end
        end

        % Value changed function: ButterworthLPFSwitch
        function ButterworthLPFSwitchValueChanged(app, event)
            if strcmp(app.ButterworthLPFSwitch.Value, 'on')
                app.ButterworthLPFSlider.Enable = 'on';
                app.ButterworthLPFField.Enable = 'on';
                app.ButterworthLPFSpinner.Enable = 'on';
            else
                app.ButterworthLPFSlider.Enable = 'off';
                app.ButterworthLPFField.Enable = 'off';
                app.ButterworthLPFSpinner.Enable = 'off';
            end
        end

        % Value changed function: ChebyshevHPFSwitch
        function ChebyshevHPFSwitchValueChanged(app, event)
            if strcmp(app.ChebyshevHPFSwitch.Value, 'on')
                app.ChebyshevHPFSlider.Enable = 'on';
                app.ChebyshevHPFpbSlider.Enable = 'on';
                app.ChebyshevHPFField.Enable = 'on';
                app.ChebyshevHPFSpinner.Enable = 'on';
            else
                app.ChebyshevHPFSlider.Enable = 'off';
                app.ChebyshevHPFpbSlider.Enable = 'off';
                app.ChebyshevHPFField.Enable = 'off';
                app.ChebyshevHPFSpinner.Enable = 'off';
            end
        end

        % Value changed function: ChebyshevLPFSwitch
        function ChebyshevLPFSwitchValueChanged(app, event)
            if strcmp(app.ChebyshevLPFSwitch.Value, 'on')
                app.ChebyshevLPFSlider.Enable = 'on';
                app.ChebyshevLPFpbSlider.Enable = 'on';
                app.ChebyshevLPFField.Enable = 'on';
                app.ChebyshevLPFSpinner.Enable = 'on';
            else
                app.ChebyshevLPFSlider.Enable = 'off';
                app.ChebyshevLPFpbSlider.Enable = 'off';
                app.ChebyshevLPFField.Enable = 'off';
                app.ChebyshevLPFSpinner.Enable = 'off';
            end
        end

        % Value changed function: ButterworthBSFSwitch
        function ButterworthBSFSwitchValueChanged(app, event)
            if strcmp(app.ButterworthBSFSwitch.Value, 'on')
                app.ButterworthBSFfclSlider.Enable = 'on';
                app.ButterworthBSFfchSlider.Enable = 'on';
                app.ButterworthBSFfclField.Enable = 'on';
                app.ButterworthBSFfchField.Enable = 'on';
                app.ButterworthBSFSpinner.Enable = 'on';
            else
                app.ButterworthBSFfclSlider.Enable = 'off';
                app.ButterworthBSFfchSlider.Enable = 'off';
                app.ButterworthBSFfclField.Enable = 'off';
                app.ButterworthBSFfchField.Enable = 'off';
                app.ButterworthBSFSpinner.Enable = 'off';
            end
        end

        % Value changed function: ButterworthBPFSwitch
        function ButterworthBPFSwitchValueChanged(app, event)
            if strcmp(app.ButterworthBPFSwitch.Value, 'on')
                app.ButterworthBPFfclSlider.Enable = 'on';
                app.ButterworthBPFfchSlider.Enable = 'on';
                app.ButterworthBPFfclField.Enable = 'on';
                app.ButterworthBPFfchField.Enable = 'on';
                app.ButterworthBPFSpinner.Enable = 'on';
            else
                app.ButterworthBPFfclSlider.Enable = 'off';
                app.ButterworthBPFfchSlider.Enable = 'off';
                app.ButterworthBPFfclField.Enable = 'off';
                app.ButterworthBPFfchField.Enable = 'off';
                app.ButterworthBPFSpinner.Enable = 'off';
            end
        end

        % Value changed function: ChebyshevBPFSwitch_2
        function ChebyshevBPFSwitch_2ValueChanged(app, event)
            if strcmp(app.ChebyshevBPFSwitch_2.Value, 'on')
                app.ChebyshevBPFfclSlider_2.Enable = 'on';
                app.ChebyshevBPFfchSlider_2.Enable = 'on';
                app.ChebyshevBPFfclField_2.Enable = 'on';
                app.ChebyshevBPFfchField_2.Enable = 'on';
                app.ChebyshevBPFSpinner_2.Enable = 'on';
            else
                app.ChebyshevBPFfclSlider_2.Enable = 'off';
                app.ChebyshevBPFfchSlider_2.Enable = 'off';
                app.ChebyshevBPFfclField_2.Enable = 'off';
                app.ChebyshevBPFfchField_2.Enable = 'off';
                app.ChebyshevBPFSpinner_2.Enable = 'off';
            end
        end

        % Value changed function: ChebyshevBPFSwitch
        function ChebyshevBPFSwitchValueChanged(app, event)
            if strcmp(app.ChebyshevBPFSwitch.Value, 'on')
                app.ChebyshevBPFfclSlider.Enable = 'on';
                app.ChebyshevBPFfchSlider.Enable = 'on';
                app.ChebyshevBPFfclField.Enable = 'on';
                app.ChebyshevBPFfchField.Enable = 'on';
                app.ChebyshevBPFSpinner.Enable = 'on';
            else
                app.ChebyshevBPFfclSlider.Enable = 'off';
                app.ChebyshevBPFfchSlider.Enable = 'off';
                app.ChebyshevBPFfclField.Enable = 'off';
                app.ChebyshevBPFfchField.Enable = 'off';
                app.ChebyshevBPFSpinner.Enable = 'off';
            end
            
        end

        % Button pushed function: envPlayButton
        function envPlayButtonPushed(app, event)
            totaltime(app);
            cla(app.envAxes,'reset')
            envelope(app);
            envplot(app);
            envplay(app);
        end

        % Value changed function: ButterworthLPFSlider
        function ButterworthLPFSliderValueChanged(app, event)
            app.ButterworthLPFField.Value = app.ButterworthLPFSlider.Value;
            
        end

        % Value changed function: ButterworthLPFField
        function ButterworthLPFFieldValueChanged(app, event)
            app.ButterworthLPFSlider.Value = app.ButterworthLPFField.Value;
            
        end

        % Value changed function: ButterworthHPFSlider
        function ButterworthHPFSliderValueChanged(app, event)
            app.ButterworthHPFField.Value = app.ButterworthHPFSlider.Value;
            
        end

        % Value changed function: ButterworthHPFField
        function ButterworthHPFFieldValueChanged(app, event)
            app.ButterworthHPFSlider.Value = app.ButterworthHPFField.Value;
            
        end

        % Value changed function: ChebyshevHPFSlider
        function ChebyshevHPFSliderValueChanged(app, event)
            app.ChebyshevHPFField.Value = app.ChebyshevHPFSlider.Value;
            
        end

        % Value changed function: ChebyshevHPFField
        function ChebyshevHPFFieldValueChanged(app, event)
            app.ChebyshevHPFSlider.Value = app.ChebyshevHPFField.Value;
            
        end

        % Value changed function: ChebyshevLPFSlider
        function ChebyshevLPFSliderValueChanged(app, event)
            app.ChebyshevLPFField.Value = app.ChebyshevLPFSlider.Value;
            
        end

        % Value changed function: ChebyshevLPFField
        function ChebyshevLPFFieldValueChanged(app, event)
            app.ChebyshevLPFSlider.Value = app.ChebyshevLPFField.Value;
            
        end

        % Value changed function: ButterworthBSFfclSlider
        function ButterworthBSFfclSliderValueChanged(app, event)
            app.ButterworthBSFfclField.Value = app.ButterworthBSFfclSlider.Value;
            
        end

        % Value changed function: ButterworthBSFfchSlider
        function ButterworthBSFfchSliderValueChanged(app, event)
            app.ButterworthBSFfchField.Value = app.ButterworthBSFfchSlider.Value;
            
        end

        % Value changed function: ButterworthBSFfclField
        function ButterworthBSFfclFieldValueChanged(app, event)
           app.ButterworthBSFfclSlider.Value = app.ButterworthBSFfclField.Value;
            
        end

        % Value changed function: ButterworthBSFfchField
        function ButterworthBSFfchFieldValueChanged(app, event)
            app.ButterworthBSFfchSlider.Value = app.ButterworthBSFfchField.Value;
            
        end

        % Value changed function: ButterworthBPFfclSlider
        function ButterworthBPFfclSliderValueChanged(app, event)
            app.ButterworthBPFfclField.Value = app.ButterworthBPFfclSlider.Value;
            
        end

        % Value changed function: ButterworthBPFfchSlider
        function ButterworthBPFfchSliderValueChanged(app, event)
            app.ButterworthBPFfchField.Value = app.ButterworthBPFfchSlider.Value;
            
        end

        % Value changed function: ButterworthBPFfclField
        function ButterworthBPFfclFieldValueChanged(app, event)
            app.ButterworthBPFfclSlider.Value = app.ButterworthBPFfclField.Value;
            
        end

        % Value changed function: ButterworthBPFfchField
        function ButterworthBPFfchFieldValueChanged(app, event)
            app.ButterworthBPFfchSlider.Value = app.ButterworthBPFfchField.Value;
            
        end

        % Value changed function: ChebyshevBPFfclSlider
        function ChebyshevBPFfclSliderValueChanged(app, event)
            app.ChebyshevBPFfclField.Value = app.ChebyshevBPFfclSlider.Value;
            
        end

        % Value changed function: ChebyshevBPFfchSlider
        function ChebyshevBPFfchSliderValueChanged(app, event)
            app.ChebyshevBPFfchField.Value = app.ChebyshevBPFfchSlider.Value;
            
        end

        % Value changed function: ChebyshevBPFfclField
        function ChebyshevBPFfclFieldValueChanged(app, event)
            app.ChebyshevBPFfclSlider.Value = app.ChebyshevBPFfclField.Value;
            
        end

        % Value changed function: ChebyshevBPFfchField
        function ChebyshevBPFfchFieldValueChanged(app, event)
            app.ChebyshevBPFfchSlider.Value = app.ChebyshevBPFfchField.Value;
            
        end

        % Value changed function: ChebyshevBPFfclSlider_2
        function ChebyshevBPFfclSlider_2ValueChanged(app, event)
            app.ChebyshevBPFfclField_2.Value = app.ChebyshevBPFfclSlider_2.Value;
            
        end

        % Value changed function: ChebyshevBPFfchSlider_2
        function ChebyshevBPFfchSlider_2ValueChanged(app, event)
            app.ChebyshevBPFfchField_2.Value = app.ChebyshevBPFfchSlider_2.Value;
            
        end

        % Value changed function: ChebyshevBPFfclField_2
        function ChebyshevBPFfclField_2ValueChanged(app, event)
            app.ChebyshevBPFfclSlider_2.Value = app.ChebyshevBPFfclField_2.Value;
            
        end

        % Value changed function: ChebyshevBPFfchField_2
        function ChebyshevBPFfchField_2ValueChanged(app, event)
            app.ChebyshevBPFfchSlider_2.Value = app.ChebyshevBPFfchField_2.Value;
            
        end

        % Button pushed function: NoteButton
        function NoteButtonPushed(app, event)
            if strcmp(app.SaveSwitch.Value, 'Save')
                presave(app);
                app.n1y = app.ampy;
            else
                app.y = app.n1y;
                play(app);
            end
            
        end

        % Button pushed function: NoteButton_2
        function NoteButton_2Pushed(app, event)
            if strcmp(app.SaveSwitch.Value, 'Save')
                presave(app);
                app.n2y = app.ampy;
            else
                app.y = app.n2y;
                play(app);
            end
        end

        % Button pushed function: NoteButton_3
        function NoteButton_3Pushed(app, event)
            if strcmp(app.SaveSwitch.Value, 'Save')
                presave(app);
                app.n3y = app.ampy;
            else
                app.y = app.n3y;
                play(app);
            end
        end

        % Button pushed function: NoteButton_4
        function NoteButton_4Pushed(app, event)
            if strcmp(app.SaveSwitch.Value, 'Save')
                presave(app);
                app.n4y = app.ampy;
            else
                app.y = app.n4y;
                play(app);
            end
        end

        % Button pushed function: NoteButton_5
        function NoteButton_5Pushed(app, event)
            if strcmp(app.SaveSwitch.Value, 'Save')
                presave(app);
                app.n5y = app.ampy;
            else
                app.y = app.n5y;
                play(app);
            end
        end

        % Button pushed function: NoteButton_6
        function NoteButton_6Pushed(app, event)
            if strcmp(app.SaveSwitch.Value, 'Save')
                presave(app);
                app.n6y = app.ampy;
            else
                app.y = app.n6y;
                play(app);
            end
        end

        % Button pushed function: NoteButton_7
        function NoteButton_7Pushed(app, event)
            if strcmp(app.SaveSwitch.Value, 'Save')
                presave(app);
                app.n7y = app.ampy;
            else
                app.y = app.n7y;
                play(app);
            end
        end

        % Button pushed function: NoteButton_8
        function NoteButton_8Pushed(app, event)
            if strcmp(app.SaveSwitch.Value, 'Save')
                presave(app);
                app.n8y = app.ampy;
            else
                app.y = app.n8y;
                play(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.OscillatorPanel = uipanel(app.UIFigure);
            app.OscillatorPanel.Title = 'Oscillator';
            app.oscWaveKnob = uiknob(app.OscillatorPanel, 'discrete');
            app.oscWaveKnob.Items = {'Off', 'Sine', 'Triangle', 'Square', 'Sawtooth', 'Noise'};
            app.oscWaveKnob.Value = 'Sine';
            app.oscAmpKnob = uiknob(app.OscillatorPanel, 'continuous');
            app.oscAmpKnob.Limits = [0 1];
            app.oscAmpKnob.Value = 1;
            app.oscFreqField = uieditfield(app.OscillatorPanel, 'numeric');
            app.oscFreqField.Limits = [60 22400];
            app.oscFreqField.Value = 440;
            app.oscWaveKnob_2 = uiknob(app.OscillatorPanel, 'discrete');
            app.oscWaveKnob_2.Items = {'Off', 'Sine', 'Triangle', 'Square', 'Sawtooth', 'Noise'};
            app.oscAmpKnob_2 = uiknob(app.OscillatorPanel, 'continuous');
            app.oscAmpKnob_2.Limits = [0 1];
            app.oscAmpKnob_2.Value = 1;
            app.oscFreqField_2 = uieditfield(app.OscillatorPanel, 'numeric');
            app.oscFreqField_2.Limits = [60 22400];
            app.oscFreqField_2.Value = 293.7;
            app.oscWaveKnob_3 = uiknob(app.OscillatorPanel, 'discrete');
            app.oscWaveKnob_3.Items = {'Off', 'Sine', 'Triangle', 'Square', 'Sawtooth', 'Noise'};
            app.oscAmpKnob_3 = uiknob(app.OscillatorPanel, 'continuous');
            app.oscAmpKnob_3.Limits = [0 1];
            app.oscAmpKnob_3.Value = 1;
            app.oscFreqField_3 = uieditfield(app.OscillatorPanel, 'numeric');
            app.oscFreqField_3.Limits = [60 22400];
            app.oscFreqField_3.Value = 329.628;
            app.oscAxes = uiaxes(app.OscillatorPanel);
            app.ALabel = uilabel(app.OscillatorPanel);
            app.ALabel.Text = 'A';
            app.fLabel = uilabel(app.OscillatorPanel);
            app.fLabel.Text = 'f';
            app.oscWaveKnob_4 = uiknob(app.OscillatorPanel, 'discrete');
            app.oscWaveKnob_4.Items = {'Off', 'Sine', 'Triangle', 'Square', 'Sawtooth', 'Noise'};
            app.oscAmpKnob_4 = uiknob(app.OscillatorPanel, 'continuous');
            app.oscAmpKnob_4.Limits = [0 1];
            app.oscAmpKnob_4.Value = 1;
            app.oscFreqField_4 = uieditfield(app.OscillatorPanel, 'numeric');
            app.oscFreqField_4.Limits = [60 22400];
            app.oscFreqField_4.Value = 391.995;
            app.oscWaveKnob_5 = uiknob(app.OscillatorPanel, 'discrete');
            app.oscWaveKnob_5.Items = {'Off', 'Sine', 'Triangle', 'Square', 'Sawtooth', 'Noise'};
            app.oscAmpKnob_5 = uiknob(app.OscillatorPanel, 'continuous');
            app.oscAmpKnob_5.Limits = [0 1];
            app.oscAmpKnob_5.Value = 1;
            app.oscFreqField_5 = uieditfield(app.OscillatorPanel, 'numeric');
            app.oscFreqField_5.Limits = [60 22400];
            app.oscFreqField_5.Value = 493.9;
            app.oscHarmSpinner = uispinner(app.OscillatorPanel);
            app.oscHarmSpinner.Limits = [1 9];
            app.oscHarmSpinner.ValueDisplayFormat = '%11.2g';
            app.oscHarmSpinner.Value = 1;
            app.oscHarmSpinner_2 = uispinner(app.OscillatorPanel);
            app.oscHarmSpinner_2.Limits = [1 9];
            app.oscHarmSpinner_2.ValueDisplayFormat = '%11.2g';
            app.oscHarmSpinner_2.Value = 1;
            app.oscWaveKnob_6 = uiknob(app.OscillatorPanel, 'discrete');
            app.oscWaveKnob_6.Items = {'Off', 'Sine', 'Triangle', 'Square', 'Sawtooth', 'Noise'};
            app.oscAmpKnob_6 = uiknob(app.OscillatorPanel, 'continuous');
            app.oscAmpKnob_6.Limits = [0 1];
            app.oscAmpKnob_6.Value = 1;
            app.oscFreqField_6 = uieditfield(app.OscillatorPanel, 'numeric');
            app.oscFreqField_6.Limits = [60 22400];
            app.oscFreqField_6.Value = 659;
            app.KeyPressDurationsecEditFieldLabel = uilabel(app.UIFigure);
            app.KeyPressDurationsecEditFieldLabel.Text = 'Key Press Duration (sec)';
            app.keyDurationField = uieditfield(app.UIFigure, 'numeric');
            app.keyDurationField.Value = 0.5;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '▷';
            app.FilterPanel = uipanel(app.UIFigure);
            app.FilterPanel.Title = 'Filter';
            app.ButterworthLPFSlider = uislider(app.FilterPanel);
            app.ButterworthLPFSlider.Limits = [60 20000];
            app.ButterworthLPFSlider.MajorTickLabels = {'60', '', '2e+04'};
            app.ButterworthLPFSlider.ValueChangedFcn = createCallbackFcn(app, @ButterworthLPFSliderValueChanged, true);
            app.ButterworthLPFSlider.Enable = 'off';
            app.ButterworthLPFSlider.Value = 8000;
            app.filterAxes = uiaxes(app.FilterPanel);
            xlabel(app.filterAxes, 'Hz');
            app.ButterworthHPFSlider = uislider(app.FilterPanel);
            app.ButterworthHPFSlider.Limits = [20 20000];
            app.ButterworthHPFSlider.MajorTickLabels = {'20', '', '2e+04'};
            app.ButterworthHPFSlider.ValueChangedFcn = createCallbackFcn(app, @ButterworthHPFSliderValueChanged, true);
            app.ButterworthHPFSlider.Enable = 'off';
            app.ButterworthHPFSlider.Value = 60;
            app.ButterworthHPFSwitch = uiswitch(app.FilterPanel, 'slider');
            app.ButterworthHPFSwitch.Items = {'off', 'on'};
            app.ButterworthHPFSwitch.ValueChangedFcn = createCallbackFcn(app, @ButterworthHPFSwitchValueChanged, true);
            app.ButterworthHPFSwitch.Value = 'off';
            app.ButterHPFLabel = uilabel(app.FilterPanel);
            app.ButterHPFLabel.Text = 'Butter HPF';
            app.ButterworthLPFSwitch = uiswitch(app.FilterPanel, 'slider');
            app.ButterworthLPFSwitch.Items = {'off', 'on'};
            app.ButterworthLPFSwitch.ValueChangedFcn = createCallbackFcn(app, @ButterworthLPFSwitchValueChanged, true);
            app.ButterworthLPFSwitch.Value = 'off';
            app.ButterLPFLabel = uilabel(app.FilterPanel);
            app.ButterLPFLabel.Text = 'Butter LPF';
            app.ChebyshevHPFSwitch = uiswitch(app.FilterPanel, 'slider');
            app.ChebyshevHPFSwitch.Items = {'off', 'on'};
            app.ChebyshevHPFSwitch.ValueChangedFcn = createCallbackFcn(app, @ChebyshevHPFSwitchValueChanged, true);
            app.ChebyshevHPFSwitch.Value = 'off';
            app.Cheby1HPFLabel = uilabel(app.FilterPanel);
            app.Cheby1HPFLabel.Text = 'Cheby1 HPF';
            app.ChebyshevHPFSlider = uislider(app.FilterPanel);
            app.ChebyshevHPFSlider.Limits = [20 20000];
            app.ChebyshevHPFSlider.MajorTickLabels = {'20', '', '2e+04'};
            app.ChebyshevHPFSlider.ValueChangedFcn = createCallbackFcn(app, @ChebyshevHPFSliderValueChanged, true);
            app.ChebyshevHPFSlider.Enable = 'off';
            app.ChebyshevHPFSlider.Value = 60;
            app.ChebyshevHPFpbSlider = uislider(app.FilterPanel);
            app.ChebyshevHPFpbSlider.Limits = [1 30];
            app.ChebyshevHPFpbSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '30'};
            app.ChebyshevHPFpbSlider.Enable = 'off';
            app.ChebyshevHPFpbSlider.Value = 10;
            app.ChebyshevLPFSwitch = uiswitch(app.FilterPanel, 'slider');
            app.ChebyshevLPFSwitch.Items = {'off', 'on'};
            app.ChebyshevLPFSwitch.ValueChangedFcn = createCallbackFcn(app, @ChebyshevLPFSwitchValueChanged, true);
            app.ChebyshevLPFSwitch.Value = 'off';
            app.Cheby1LPFLabel = uilabel(app.FilterPanel);
            app.Cheby1LPFLabel.Text = 'Cheby1 LPF';
            app.ChebyshevLPFSlider = uislider(app.FilterPanel);
            app.ChebyshevLPFSlider.Limits = [60 20000];
            app.ChebyshevLPFSlider.MajorTickLabels = {'60', '', '2e+04'};
            app.ChebyshevLPFSlider.ValueChangedFcn = createCallbackFcn(app, @ChebyshevLPFSliderValueChanged, true);
            app.ChebyshevLPFSlider.Enable = 'off';
            app.ChebyshevLPFSlider.Value = 8000;
            app.ChebyshevLPFpbSlider = uislider(app.FilterPanel);
            app.ChebyshevLPFpbSlider.Limits = [1 30];
            app.ChebyshevLPFpbSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '30'};
            app.ChebyshevLPFpbSlider.Enable = 'off';
            app.ChebyshevLPFpbSlider.Value = 10;
            app.ButterworthBSFfclSlider = uislider(app.FilterPanel);
            app.ButterworthBSFfclSlider.Limits = [60 20000];
            app.ButterworthBSFfclSlider.MajorTickLabels = {'60', '', '2e+04'};
            app.ButterworthBSFfclSlider.ValueChangedFcn = createCallbackFcn(app, @ButterworthBSFfclSliderValueChanged, true);
            app.ButterworthBSFfclSlider.Enable = 'off';
            app.ButterworthBSFfclSlider.Value = 8000;
            app.ButterworthBSFSwitch = uiswitch(app.FilterPanel, 'slider');
            app.ButterworthBSFSwitch.Items = {'off', 'on'};
            app.ButterworthBSFSwitch.ValueChangedFcn = createCallbackFcn(app, @ButterworthBSFSwitchValueChanged, true);
            app.ButterworthBSFSwitch.Value = 'off';
            app.ButterBSFLabel = uilabel(app.FilterPanel);
            app.ButterBSFLabel.Text = 'Butter BSF';
            app.ButterworthBSFfchSlider = uislider(app.FilterPanel);
            app.ButterworthBSFfchSlider.Limits = [60 20000];
            app.ButterworthBSFfchSlider.MajorTickLabels = {'60', '', '2e+04'};
            app.ButterworthBSFfchSlider.ValueChangedFcn = createCallbackFcn(app, @ButterworthBSFfchSliderValueChanged, true);
            app.ButterworthBSFfchSlider.Enable = 'off';
            app.ButterworthBSFfchSlider.Value = 60;
            app.ButterworthBPFfclSlider = uislider(app.FilterPanel);
            app.ButterworthBPFfclSlider.Limits = [60 20000];
            app.ButterworthBPFfclSlider.MajorTickLabels = {'60', '1.003e+04', '2e+04'};
            app.ButterworthBPFfclSlider.ValueChangedFcn = createCallbackFcn(app, @ButterworthBPFfclSliderValueChanged, true);
            app.ButterworthBPFfclSlider.Enable = 'off';
            app.ButterworthBPFfclSlider.Value = 8000;
            app.ButterworthBPFSwitch = uiswitch(app.FilterPanel, 'slider');
            app.ButterworthBPFSwitch.Items = {'off', 'on'};
            app.ButterworthBPFSwitch.ValueChangedFcn = createCallbackFcn(app, @ButterworthBPFSwitchValueChanged, true);
            app.ButterworthBPFSwitch.Value = 'off';
            app.ButterBPFLabel = uilabel(app.FilterPanel);
            app.ButterBPFLabel.Text = 'Butter BPF';
            app.ButterworthBPFfchSlider = uislider(app.FilterPanel);
            app.ButterworthBPFfchSlider.Limits = [60 20000];
            app.ButterworthBPFfchSlider.MajorTickLabels = {'60', '1.003e+04', '2e+04'};
            app.ButterworthBPFfchSlider.ValueChangedFcn = createCallbackFcn(app, @ButterworthBPFfchSliderValueChanged, true);
            app.ButterworthBPFfchSlider.Enable = 'off';
            app.ButterworthBPFfchSlider.Value = 60;
            app.ChebyshevBPFfclSlider_2 = uislider(app.FilterPanel);
            app.ChebyshevBPFfclSlider_2.Limits = [60 20000];
            app.ChebyshevBPFfclSlider_2.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFfclSlider_2ValueChanged, true);
            app.ChebyshevBPFfclSlider_2.Enable = 'off';
            app.ChebyshevBPFfclSlider_2.Value = 8000;
            app.ChebyshevBPFSwitch_2 = uiswitch(app.FilterPanel, 'slider');
            app.ChebyshevBPFSwitch_2.Items = {'off', 'on'};
            app.ChebyshevBPFSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFSwitch_2ValueChanged, true);
            app.ChebyshevBPFSwitch_2.Value = 'off';
            app.Cheby2BPFLabel = uilabel(app.FilterPanel);
            app.Cheby2BPFLabel.Text = 'Cheby2 BPF';
            app.ChebyshevBPFfchSlider_2 = uislider(app.FilterPanel);
            app.ChebyshevBPFfchSlider_2.Limits = [60 20000];
            app.ChebyshevBPFfchSlider_2.MajorTickLabels = {'60', '1.003e+04', '2e+04'};
            app.ChebyshevBPFfchSlider_2.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFfchSlider_2ValueChanged, true);
            app.ChebyshevBPFfchSlider_2.Enable = 'off';
            app.ChebyshevBPFfchSlider_2.Value = 60;
            app.ChebyshevBPFfclSlider = uislider(app.FilterPanel);
            app.ChebyshevBPFfclSlider.Limits = [60 20000];
            app.ChebyshevBPFfclSlider.MajorTickLabels = {'60', '1.003e+04', '2e+04'};
            app.ChebyshevBPFfclSlider.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFfclSliderValueChanged, true);
            app.ChebyshevBPFfclSlider.Enable = 'off';
            app.ChebyshevBPFfclSlider.Value = 8000;
            app.ChebyshevBPFSwitch = uiswitch(app.FilterPanel, 'slider');
            app.ChebyshevBPFSwitch.Items = {'off', 'on'};
            app.ChebyshevBPFSwitch.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFSwitchValueChanged, true);
            app.ChebyshevBPFSwitch.Value = 'off';
            app.Cheby1BPFLabel = uilabel(app.FilterPanel);
            app.Cheby1BPFLabel.Text = 'Cheby1 BPF';
            app.ChebyshevBPFfchSlider = uislider(app.FilterPanel);
            app.ChebyshevBPFfchSlider.Limits = [60 20000];
            app.ChebyshevBPFfchSlider.MajorTickLabels = {'60', '1.003e+04', '2e+04'};
            app.ChebyshevBPFfchSlider.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFfchSliderValueChanged, true);
            app.ChebyshevBPFfchSlider.Enable = 'off';
            app.ChebyshevBPFfchSlider.Value = 60;
            app.ButterworthHPFField = uieditfield(app.FilterPanel, 'numeric');
            app.ButterworthHPFField.ValueChangedFcn = createCallbackFcn(app, @ButterworthHPFFieldValueChanged, true);
            app.ButterworthHPFField.Limits = [20 20000];
            app.ButterworthHPFField.Enable = 'off';
            app.ButterworthHPFField.Value = 60;
            app.ButterworthLPFField = uieditfield(app.FilterPanel, 'numeric');
            app.ButterworthLPFField.ValueChangedFcn = createCallbackFcn(app, @ButterworthLPFFieldValueChanged, true);
            app.ButterworthLPFField.Limits = [60 20000];
            app.ButterworthLPFField.Enable = 'off';
            app.ButterworthLPFField.Value = 8000;
            app.ButterworthBSFfclField = uieditfield(app.FilterPanel, 'numeric');
            app.ButterworthBSFfclField.ValueChangedFcn = createCallbackFcn(app, @ButterworthBSFfclFieldValueChanged, true);
            app.ButterworthBSFfclField.Limits = [60 20000];
            app.ButterworthBSFfclField.Enable = 'off';
            app.ButterworthBSFfclField.Value = 8000;
            app.ButterworthBSFfchField = uieditfield(app.FilterPanel, 'numeric');
            app.ButterworthBSFfchField.ValueChangedFcn = createCallbackFcn(app, @ButterworthBSFfchFieldValueChanged, true);
            app.ButterworthBSFfchField.Limits = [60 20000];
            app.ButterworthBSFfchField.Enable = 'off';
            app.ButterworthBSFfchField.Value = 60;
            app.ButterworthBPFfclField = uieditfield(app.FilterPanel, 'numeric');
            app.ButterworthBPFfclField.ValueChangedFcn = createCallbackFcn(app, @ButterworthBPFfclFieldValueChanged, true);
            app.ButterworthBPFfclField.Limits = [60 20000];
            app.ButterworthBPFfclField.Enable = 'off';
            app.ButterworthBPFfclField.Value = 8000;
            app.ButterworthBPFfchField = uieditfield(app.FilterPanel, 'numeric');
            app.ButterworthBPFfchField.ValueChangedFcn = createCallbackFcn(app, @ButterworthBPFfchFieldValueChanged, true);
            app.ButterworthBPFfchField.Limits = [60 20000];
            app.ButterworthBPFfchField.Enable = 'off';
            app.ButterworthBPFfchField.Value = 60;
            app.ChebyshevBPFfclField_2 = uieditfield(app.FilterPanel, 'numeric');
            app.ChebyshevBPFfclField_2.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFfclField_2ValueChanged, true);
            app.ChebyshevBPFfclField_2.Limits = [60 20000];
            app.ChebyshevBPFfclField_2.Enable = 'off';
            app.ChebyshevBPFfclField_2.Value = 8000;
            app.ChebyshevBPFfchField_2 = uieditfield(app.FilterPanel, 'numeric');
            app.ChebyshevBPFfchField_2.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFfchField_2ValueChanged, true);
            app.ChebyshevBPFfchField_2.Limits = [60 20000];
            app.ChebyshevBPFfchField_2.Enable = 'off';
            app.ChebyshevBPFfchField_2.Value = 60;
            app.ChebyshevBPFfclField = uieditfield(app.FilterPanel, 'numeric');
            app.ChebyshevBPFfclField.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFfclFieldValueChanged, true);
            app.ChebyshevBPFfclField.Limits = [60 20000];
            app.ChebyshevBPFfclField.Enable = 'off';
            app.ChebyshevBPFfclField.Value = 8000;
            app.ChebyshevBPFfchField = uieditfield(app.FilterPanel, 'numeric');
            app.ChebyshevBPFfchField.ValueChangedFcn = createCallbackFcn(app, @ChebyshevBPFfchFieldValueChanged, true);
            app.ChebyshevBPFfchField.Limits = [60 20000];
            app.ChebyshevBPFfchField.Enable = 'off';
            app.ChebyshevBPFfchField.Value = 60;
            app.ChebyshevLPFField = uieditfield(app.FilterPanel, 'numeric');
            app.ChebyshevLPFField.ValueChangedFcn = createCallbackFcn(app, @ChebyshevLPFFieldValueChanged, true);
            app.ChebyshevLPFField.Limits = [60 20000];
            app.ChebyshevLPFField.Enable = 'off';
            app.ChebyshevLPFField.Value = 8000;
            app.ChebyshevHPFField = uieditfield(app.FilterPanel, 'numeric');
            app.ChebyshevHPFField.ValueChangedFcn = createCallbackFcn(app, @ChebyshevHPFFieldValueChanged, true);
            app.ChebyshevHPFField.Limits = [20 20000];
            app.ChebyshevHPFField.Enable = 'off';
            app.ChebyshevHPFField.Value = 60;
            app.ButterworthHPFSpinner = uispinner(app.FilterPanel);
            app.ButterworthHPFSpinner.Limits = [1 9];
            app.ButterworthHPFSpinner.ValueDisplayFormat = '%11.2g';
            app.ButterworthHPFSpinner.Enable = 'off';
            app.ButterworthHPFSpinner.Value = 1;
            app.ButterworthLPFSpinner = uispinner(app.FilterPanel);
            app.ButterworthLPFSpinner.Limits = [1 9];
            app.ButterworthLPFSpinner.ValueDisplayFormat = '%11.2g';
            app.ButterworthLPFSpinner.Enable = 'off';
            app.ButterworthLPFSpinner.Value = 1;
            app.ButterworthBSFSpinner = uispinner(app.FilterPanel);
            app.ButterworthBSFSpinner.Step = 2;
            app.ButterworthBSFSpinner.Limits = [2 20];
            app.ButterworthBSFSpinner.ValueDisplayFormat = '%11.2g';
            app.ButterworthBSFSpinner.Enable = 'off';
            app.ButterworthBSFSpinner.Value = 2;
            app.ChebyshevHPFSpinner = uispinner(app.FilterPanel);
            app.ChebyshevHPFSpinner.Limits = [1 9];
            app.ChebyshevHPFSpinner.ValueDisplayFormat = '%11.2g';
            app.ChebyshevHPFSpinner.Enable = 'off';
            app.ChebyshevHPFSpinner.Value = 1;
            app.ChebyshevLPFSpinner = uispinner(app.FilterPanel);
            app.ChebyshevLPFSpinner.Limits = [1 9];
            app.ChebyshevLPFSpinner.ValueDisplayFormat = '%11.2g';
            app.ChebyshevLPFSpinner.Enable = 'off';
            app.ChebyshevLPFSpinner.Value = 1;
            app.ChebyshevBPFSpinner = uispinner(app.FilterPanel);
            app.ChebyshevBPFSpinner.Step = 2;
            app.ChebyshevBPFSpinner.Limits = [2 20];
            app.ChebyshevBPFSpinner.ValueDisplayFormat = '%11.2g';
            app.ChebyshevBPFSpinner.Enable = 'off';
            app.ChebyshevBPFSpinner.Value = 2;
            app.ChebyshevBPFSpinner_2 = uispinner(app.FilterPanel);
            app.ChebyshevBPFSpinner_2.Step = 2;
            app.ChebyshevBPFSpinner_2.Limits = [2 20];
            app.ChebyshevBPFSpinner_2.ValueDisplayFormat = '%11.2g';
            app.ChebyshevBPFSpinner_2.Enable = 'off';
            app.ChebyshevBPFSpinner_2.Value = 2;
            app.ButterworthBPFSpinner = uispinner(app.FilterPanel);
            app.ButterworthBPFSpinner.Step = 2;
            app.ButterworthBPFSpinner.Limits = [2 20];
            app.ButterworthBPFSpinner.ValueDisplayFormat = '%11.2g';
            app.ButterworthBPFSpinner.Enable = 'off';
            app.ButterworthBPFSpinner.Value = 2;
            app.EnvelopePanel = uipanel(app.UIFigure);
            app.EnvelopePanel.Title = 'Envelope';
            app.envAxes = uiaxes(app.EnvelopePanel);
            xlabel(app.envAxes, 'time');
            app.AKnobLabel = uilabel(app.EnvelopePanel);
            app.AKnobLabel.Text = 'A';
            app.AKnob = uiknob(app.EnvelopePanel, 'continuous');
            app.AKnob.Limits = [0 5000];
            app.AKnob.Value = 10;
            app.DKnobLabel = uilabel(app.EnvelopePanel);
            app.DKnobLabel.Text = 'D';
            app.DKnob = uiknob(app.EnvelopePanel, 'continuous');
            app.DKnob.Limits = [0 5000];
            app.DKnob.Value = 200;
            app.RKnobLabel = uilabel(app.EnvelopePanel);
            app.RKnobLabel.Text = 'R';
            app.RKnob = uiknob(app.EnvelopePanel, 'continuous');
            app.RKnob.Limits = [0 5000];
            app.RKnob.Value = 1000;
            app.SSliderLabel = uilabel(app.EnvelopePanel);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.EnvelopePanel);
            app.SSlider.Limits = [0 1];
            app.SSlider.Value = 0.5;
            app.AmplifierPanel = uipanel(app.UIFigure);
            app.AmplifierPanel.Title = 'Amplifier';
            app.AMPSliderLabel = uilabel(app.AmplifierPanel);
            app.AMPSliderLabel.Text = 'AMP';
            app.ampSlider = uislider(app.AmplifierPanel);
            app.ampSlider.Limits = [0 4];
            app.ampSlider.MajorTicks = [0 1 2 3 4];
            app.ampSlider.MajorTickLabels = {'0', '', '', '', '4'};
            app.ampSlider.Value = 1;
            app.envPlayButton = uibutton(app.UIFigure, 'push');
            app.envPlayButton.ButtonPushedFcn = createCallbackFcn(app, @envPlayButtonPushed, true);
            app.envPlayButton.Text = '►';
            app.filterPlayButton = uibutton(app.UIFigure, 'push');
            app.filterPlayButton.ButtonPushedFcn = createCallbackFcn(app, @filterPlayButtonPushed, true);
            app.filterPlayButton.Text = '►';
            app.oscPlayButton = uibutton(app.UIFigure, 'push');
            app.oscPlayButton.ButtonPushedFcn = createCallbackFcn(app, @oscPlayButtonPushed, true);
            app.oscPlayButton.Text = '►';
            app.msynthLabel = uilabel(app.UIFigure);
            app.msynthLabel.Text = 'm-synth ♫';
            app.SavedPresetPanel = uipanel(app.UIFigure);
            app.SavedPresetPanel.Title = 'Saved Preset';
            app.SaveSwitch = uiswitch(app.SavedPresetPanel, 'rocker');
            app.SaveSwitch.Items = {'Save', 'Play'};
            app.SaveSwitch.Value = 'Save';
            app.NoteButton = uibutton(app.SavedPresetPanel, 'push');
            app.NoteButton.ButtonPushedFcn = createCallbackFcn(app, @NoteButtonPushed, true);
            app.NoteButton.Text = '1';
            app.NoteButton_2 = uibutton(app.SavedPresetPanel, 'push');
            app.NoteButton_2.ButtonPushedFcn = createCallbackFcn(app, @NoteButton_2Pushed, true);
            app.NoteButton_2.Text = '2';
            app.NoteButton_3 = uibutton(app.SavedPresetPanel, 'push');
            app.NoteButton_3.ButtonPushedFcn = createCallbackFcn(app, @NoteButton_3Pushed, true);
            app.NoteButton_3.Text = '3';
            app.NoteButton_4 = uibutton(app.SavedPresetPanel, 'push');
            app.NoteButton_4.ButtonPushedFcn = createCallbackFcn(app, @NoteButton_4Pushed, true);
            app.NoteButton_4.Text = '4';
            app.NoteButton_5 = uibutton(app.SavedPresetPanel, 'push');
            app.NoteButton_5.ButtonPushedFcn = createCallbackFcn(app, @NoteButton_5Pushed, true);
            app.NoteButton_5.Text = '5';
            app.NoteButton_6 = uibutton(app.SavedPresetPanel, 'push');
            app.NoteButton_6.ButtonPushedFcn = createCallbackFcn(app, @NoteButton_6Pushed, true);
            app.NoteButton_6.Text = '6';
            app.NoteButton_7 = uibutton(app.SavedPresetPanel, 'push');
            app.NoteButton_7.ButtonPushedFcn = createCallbackFcn(app, @NoteButton_7Pushed, true);
            app.NoteButton_7.Text = '7';
            app.NoteButton_8 = uibutton(app.SavedPresetPanel, 'push');
            app.NoteButton_8.ButtonPushedFcn = createCallbackFcn(app, @NoteButton_8Pushed, true);
            app.NoteButton_8.Text = '8';
        end
    end

    methods (Access = public)

        % Construct app
        function app = msynth()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end